classdef ConfigCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        parse_filesEditFieldLabel       matlab.ui.control.Label
        parse_filesEditField            matlab.ui.control.EditField
        label_channelsEditFieldLabel    matlab.ui.control.Label
        label_channelsEditField         matlab.ui.control.EditField
        create_psthEditFieldLabel       matlab.ui.control.Label
        create_psthEditField            matlab.ui.control.EditField
        rf_analysisEditFieldLabel       matlab.ui.control.Label
        rf_analysisEditField            matlab.ui.control.EditField
        make_psth_graphsEditFieldLabel  matlab.ui.control.Label
        make_psth_graphsEditField       matlab.ui.control.EditField
        nv_analysisEditFieldLabel       matlab.ui.control.Label
        nv_analysisEditField            matlab.ui.control.EditField
        info_analysisEditFieldLabel     matlab.ui.control.Label
        info_analysisEditField          matlab.ui.control.EditField
        psth_classifyEditFieldLabel     matlab.ui.control.Label
        psth_classifyEditField          matlab.ui.control.EditField
        event_mapEditFieldLabel         matlab.ui.control.Label
        event_mapEditField              matlab.ui.control.EditField
        trial_rangeEditFieldLabel       matlab.ui.control.Label
        trial_rangeEditField            matlab.ui.control.EditField
        wanted_eventsEditFieldLabel     matlab.ui.control.Label
        wanted_eventsEditField          matlab.ui.control.EditField
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.EditField
        threshold_scaleEditFieldLabel   matlab.ui.control.Label
        threshold_scaleEditField        matlab.ui.control.EditField
        sig_binsEditFieldLabel          matlab.ui.control.Label
        sig_binsEditField               matlab.ui.control.EditField
        sig_checkEditFieldLabel         matlab.ui.control.Label
        sig_checkEditField              matlab.ui.control.EditField
        make_region_subplotEditFieldLabel  matlab.ui.control.Label
        make_region_subplotEditField    matlab.ui.control.EditField
        bin_sizeEditFieldLabel          matlab.ui.control.Label
        bin_sizeEditField               matlab.ui.control.EditField
        pre_timeEditFieldLabel          matlab.ui.control.Label
        pre_timeEditField               matlab.ui.control.EditField
        post_timeEditFieldLabel         matlab.ui.control.Label
        post_timeEditField              matlab.ui.control.EditField
        ignore_animalEditFieldLabel     matlab.ui.control.Label
        ignore_animalEditField          matlab.ui.control.EditField
        total_trialsEditFieldLabel      matlab.ui.control.Label
        total_trialsEditField           matlab.ui.control.EditField
        trial_lower_boundEditFieldLabel  matlab.ui.control.Label
        trial_lower_boundEditField      matlab.ui.control.EditField
        total_eventsEditFieldLabel      matlab.ui.control.Label
        total_eventsEditField           matlab.ui.control.EditField
        is_non_strobed_and_strobedEditFieldLabel  matlab.ui.control.Label
        is_non_strobed_and_strobedEditField  matlab.ui.control.EditField
        sub_columnsEditFieldLabel       matlab.ui.control.Label
        sub_columnsEditField            matlab.ui.control.EditField
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.EditField
        norm_var_scalingEditFieldLabel  matlab.ui.control.Label
        norm_var_scalingEditField       matlab.ui.control.EditField
        separate_eventsLabel            matlab.ui.control.Label
        separate_eventsEditField        matlab.ui.control.EditField
        bootstrap_classifierEditFieldLabel  matlab.ui.control.Label
        bootstrap_classifierEditField   matlab.ui.control.EditField
        boot_iterationsEditFieldLabel   matlab.ui.control.Label
        boot_iterationsEditField        matlab.ui.control.EditField
        NAEditField_2Label              matlab.ui.control.Label
        NAEditField_2                   matlab.ui.control.EditField
        NAEditField_3Label              matlab.ui.control.Label
        NAEditField_3                   matlab.ui.control.EditField
        CompileButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
            parse_files = app.parse_filesEditField.Value;
            label_channels = app.label_channelsEditField.Value;
            create_psth = app.create_psthEditField.Value;
            rf_analysis = app.rf_analysisEditField.Value;
            make_psth_graphs = app.make_psth_graphsEditField.Value;
            nv_analysis = app.nv_analysisEditField.Value;
            info_analysis = app.info_analysisEditField.Value;
            psth_classify = app.psth_classifyEditField.Value;
            bin_size = app.bin_sizeEditField.Value;
            pre_time = app.pre_timeEditField.Value;
            post_time = app.post_timeEditField.Value;
            ignore_animal = app.ignore_animalEditField.Value;
            total_trials = app.total_trialsEditField.Value;
            trial_lower_bound = app.trial_lower_boundEditField.Value;
            total_events = app.total_eventsEditField.Value;
            is_non_strobed_and_strobed = app.is_non_strobed_and_strobedEditField.Value;
            event_map = app.event_mapEditField.Value;
            trial_range = app.trial_rangeEditField.Value;
            wanted_events = app.wanted_eventsEditField.Value;
            span = app.spanEditField.Value;
            threshold_scale = app.threshold_scaleEditField.Value;
            sig_bins = app.sig_binsEditField.Value;
            sig_check = app.sig_checkEditField.Value;
            make_region_subplot = app.make_region_subplotEditField.Value;
            sub_columns = app.sub_columnsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            norm_var_scaling = app.norm_var_scalingEditField.Value;
            separate_events = app.separate_eventsEditField.Value;
            bootstrap_classifier = app.bootstrap_classifierEditField.Value;
            boot_iterations = app.boot_iterationsEditField.Value;
            
            % Put variable names in here
            variables = ["parse_files" "label_channels" "create_psth" "rf_analysis" "make_psth_graphs" "nv_analysis" "info_analysis" ...
                "psth_classify" "bin_size" "pre_time" "post_time" "ignore_animal" "total_trials" "trial_lower_bound" ...
                "total_events" "is_non_strobed_and_strobed" "event_map" "trial_range" "wanted_events" "span" "threshold_scale" ...
                "sig_bins" "sig_check" "make_region_subplot" "sub_colums" "epsilon" "norm_var_scaling" "separate_events" ...
                "bootstrap_classifier" "boot_iterations"];
            % Put variable values in here
            values = [string(parse_files),label_channels,create_psth,rf_analysis,make_psth_graphs,nv_analysis,info_analysis,psth_classify ...
                ,bin_size,pre_time,post_time,ignore_animal,total_trials,trial_lower_bound,total_events,is_non_strobed_and_strobed ...
                ,event_map,trial_range,wanted_events,span,threshold_scale,sig_bins,sig_check,make_region_subplot,sub_columns ...
                ,epsilon,norm_var_scaling,separate_events,bootstrap_classifier,boot_iterations];
            
            T = array2table(values','RowNames',variables');
            writetable(T,'configGenerator.csv','WriteRowNames',true,'WriteVariableNames',0)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.parse_filesEditFieldLabel = uilabel(app.UIFigure);
            app.parse_filesEditFieldLabel.Text = 'parse_files';
            app.parse_filesEditField = uieditfield(app.UIFigure, 'text');
            app.parse_filesEditField.Value = 'true';
            app.label_channelsEditFieldLabel = uilabel(app.UIFigure);
            app.label_channelsEditFieldLabel.Text = 'label_channels';
            app.label_channelsEditField = uieditfield(app.UIFigure, 'text');
            app.label_channelsEditField.Tooltip = {''};
            app.label_channelsEditField.Value = 'true';
            app.create_psthEditFieldLabel = uilabel(app.UIFigure);
            app.create_psthEditFieldLabel.Text = 'create_psth';
            app.create_psthEditField = uieditfield(app.UIFigure, 'text');
            app.create_psthEditField.Value = 'true';
            app.rf_analysisEditFieldLabel = uilabel(app.UIFigure);
            app.rf_analysisEditFieldLabel.Text = 'rf_analysis';
            app.rf_analysisEditField = uieditfield(app.UIFigure, 'text');
            app.rf_analysisEditField.Value = 'true';
            app.make_psth_graphsEditFieldLabel = uilabel(app.UIFigure);
            app.make_psth_graphsEditFieldLabel.Text = 'make_psth_graphs	';
            app.make_psth_graphsEditField = uieditfield(app.UIFigure, 'text');
            app.make_psth_graphsEditField.Value = 'true';
            app.nv_analysisEditFieldLabel = uilabel(app.UIFigure);
            app.nv_analysisEditFieldLabel.Text = 'nv_analysis';
            app.nv_analysisEditField = uieditfield(app.UIFigure, 'text');
            app.nv_analysisEditField.Value = 'true';
            app.info_analysisEditFieldLabel = uilabel(app.UIFigure);
            app.info_analysisEditFieldLabel.Text = 'info_analysis';
            app.info_analysisEditField = uieditfield(app.UIFigure, 'text');
            app.info_analysisEditField.Value = 'true';
            app.psth_classifyEditFieldLabel = uilabel(app.UIFigure);
            app.psth_classifyEditFieldLabel.Text = 'psth_classify';
            app.psth_classifyEditField = uieditfield(app.UIFigure, 'text');
            app.psth_classifyEditField.Value = 'true';
            app.event_mapEditFieldLabel = uilabel(app.UIFigure);
            app.event_mapEditFieldLabel.Text = 'event_map';
            app.event_mapEditField = uieditfield(app.UIFigure, 'text');
            app.event_mapEditField.Value = '1,3,4,6';
            app.trial_rangeEditFieldLabel = uilabel(app.UIFigure);
            app.trial_rangeEditFieldLabel.Text = 'trial_range';
            app.trial_rangeEditField = uieditfield(app.UIFigure, 'text');
            app.trial_rangeEditField.Value = '1 300';
            app.wanted_eventsEditFieldLabel = uilabel(app.UIFigure);
            app.wanted_eventsEditFieldLabel.Text = 'wanted_events';
            app.wanted_eventsEditField = uieditfield(app.UIFigure, 'text');
            app.wanted_eventsEditField.Value = '1,3,4';
            app.spanEditFieldLabel = uilabel(app.UIFigure);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.UIFigure, 'text');
            app.spanEditField.Value = '3';
            app.threshold_scaleEditFieldLabel = uilabel(app.UIFigure);
            app.threshold_scaleEditFieldLabel.Text = 'threshold_scale';
            app.threshold_scaleEditField = uieditfield(app.UIFigure, 'text');
            app.threshold_scaleEditField.Value = '1.65';
            app.sig_binsEditFieldLabel = uilabel(app.UIFigure);
            app.sig_binsEditFieldLabel.Text = 'sig_bins';
            app.sig_binsEditField = uieditfield(app.UIFigure, 'text');
            app.sig_binsEditField.Value = '3';
            app.sig_checkEditFieldLabel = uilabel(app.UIFigure);
            app.sig_checkEditFieldLabel.Text = 'sig_check';
            app.sig_checkEditField = uieditfield(app.UIFigure, 'text');
            app.sig_checkEditField.Value = '1';
            app.make_region_subplotEditFieldLabel = uilabel(app.UIFigure);
            app.make_region_subplotEditFieldLabel.Text = 'make_region_subplot';
            app.make_region_subplotEditField = uieditfield(app.UIFigure, 'text');
            app.make_region_subplotEditField.Value = 'true';
            app.bin_sizeEditFieldLabel = uilabel(app.UIFigure);
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.UIFigure, 'text');
            app.bin_sizeEditField.Value = '0.005';
            app.pre_timeEditFieldLabel = uilabel(app.UIFigure);
            app.pre_timeEditFieldLabel.Text = 'pre_time';
            app.pre_timeEditField = uieditfield(app.UIFigure, 'text');
            app.pre_timeEditField.Value = '0.1';
            app.post_timeEditFieldLabel = uilabel(app.UIFigure);
            app.post_timeEditFieldLabel.Text = 'post_time';
            app.post_timeEditField = uieditfield(app.UIFigure, 'text');
            app.post_timeEditField.Value = '0.2';
            app.ignore_animalEditFieldLabel = uilabel(app.UIFigure);
            app.ignore_animalEditFieldLabel.Text = 'ignore_animal	';
            app.ignore_animalEditField = uieditfield(app.UIFigure, 'text');
            app.ignore_animalEditField.Value = 'false';
            app.total_trialsEditFieldLabel = uilabel(app.UIFigure);
            app.total_trialsEditFieldLabel.Text = 'total_trials';
            app.total_trialsEditField = uieditfield(app.UIFigure, 'text');
            app.total_trialsEditField.Value = '100';
            app.trial_lower_boundEditFieldLabel = uilabel(app.UIFigure);
            app.trial_lower_boundEditFieldLabel.Text = 'trial_lower_bound';
            app.trial_lower_boundEditField = uieditfield(app.UIFigure, 'text');
            app.trial_lower_boundEditField.Value = '50';
            app.total_eventsEditFieldLabel = uilabel(app.UIFigure);
            app.total_eventsEditFieldLabel.Text = 'total_events';
            app.total_eventsEditField = uieditfield(app.UIFigure, 'text');
            app.total_eventsEditField.Value = '4';
            app.is_non_strobed_and_strobedEditFieldLabel = uilabel(app.UIFigure);
            app.is_non_strobed_and_strobedEditFieldLabel.Text = 'is_non_strobed_and_strobed';
            app.is_non_strobed_and_strobedEditField = uieditfield(app.UIFigure, 'text');
            app.is_non_strobed_and_strobedEditField.Value = 'true';
            app.sub_columnsEditFieldLabel = uilabel(app.UIFigure);
            app.sub_columnsEditFieldLabel.Text = 'sub_columns';
            app.sub_columnsEditField = uieditfield(app.UIFigure, 'text');
            app.sub_columnsEditField.Value = '4';
            app.epsilonEditFieldLabel = uilabel(app.UIFigure);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.UIFigure, 'text');
            app.epsilonEditField.Value = '0.01';
            app.norm_var_scalingEditFieldLabel = uilabel(app.UIFigure);
            app.norm_var_scalingEditFieldLabel.Text = 'norm_var_scaling';
            app.norm_var_scalingEditField = uieditfield(app.UIFigure, 'text');
            app.norm_var_scalingEditField.Value = '0.2';
            app.separate_eventsLabel = uilabel(app.UIFigure);
            app.separate_eventsLabel.Text = 'separate_events';
            app.separate_eventsEditField = uieditfield(app.UIFigure, 'text');
            app.separate_eventsEditField.Value = 'false';
            app.bootstrap_classifierEditFieldLabel = uilabel(app.UIFigure);
            app.bootstrap_classifierEditFieldLabel.Text = 'bootstrap_classifier';
            app.bootstrap_classifierEditField = uieditfield(app.UIFigure, 'text');
            app.bootstrap_classifierEditField.Value = 'true';
            app.boot_iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.boot_iterationsEditFieldLabel.Text = 'boot_iterations';
            app.boot_iterationsEditField = uieditfield(app.UIFigure, 'text');
            app.boot_iterationsEditField.Value = '100';
            app.NAEditField_2Label = uilabel(app.UIFigure);
            app.NAEditField_2Label.Text = 'N/A';
            app.NAEditField_2 = uieditfield(app.UIFigure, 'text');
            app.NAEditField_3Label = uilabel(app.UIFigure);
            app.NAEditField_3Label.Text = 'N/A';
            app.NAEditField_3 = uieditfield(app.UIFigure, 'text');
            app.CompileButton = uibutton(app.UIFigure, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigCSVGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end